classdef MammographySimulatorApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        DistanceSlider         matlab.ui.control.Slider
        DistanceSliderLabel    matlab.ui.control.Label
        AngleSlider            matlab.ui.control.Slider
        AngleSliderLabel       matlab.ui.control.Label
        Generate3DImageButton  matlab.ui.control.StateButton
        Generate2DImageButton  matlab.ui.control.StateButton
        EnergyDropDown         matlab.ui.control.DropDown
        EnergyDropDownLabel    matlab.ui.control.Label
        UIAxes                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Phantom2D % 2D phantom matrix for generation
        PhantomResolution = 128 %Resolution of phantom
        MuValues % Absorption coefficients for tissue types 
    end
    
    %app methods for calculations and parameter adjustments
    methods (Access = private)
        %function to update absorption coefficients (mu values) based on
        %energy
        function updateMuValues(app)
            switch app.EnergyDropDown.Value
                case '20 keV'
                    app.MuValues = [0.3, 0.7]; % [Background, Structure]
                case '30 keV'
                    app.MuValues = [0.25, 0.6];

                case '50 keV'
                    app.MuValues = [0.2, 0.5]; 
             end 
        end
        
          % Function to generate a 1D profile of the 2D phantom
        function generate2DProfile(app)
            if isempty(app.MuValues) || length(app.MuValues) < 2
                error('MuValues are not properly initialized. Check the updateMuValues function.');
            end
            % Set up a simple 2D phantom with two densities
            app.Phantom2D = zeros(app.PhantomResolution);
            radius = app.PhantomResolution / 4;
            [X, Y] = meshgrid(1:app.PhantomResolution, 1:app.PhantomResolution);
            circle = (X - app.PhantomResolution/2).^2 + (Y - app.PhantomResolution/2).^2 <= radius^2;

            % Assign µ values based on selected energy
            app.Phantom2D(circle) = app.MuValues(2); % High density region
            app.Phantom2D(~circle) = app.MuValues(1); % Background

            % Apply angle transformation if the angle is not zero
            angle = app.AngleSlider.Value;
            if angle ~= 0
                rotatedPhantom = imrotate(app.Phantom2D, angle, 'crop');
            else
                rotatedPhantom = app.Phantom2D;
            end

            % Generate 1D profile by summing across one axis
            profile = sum(rotatedPhantom, 1);

            % Scale profile based on distance (inverse-square law)
            distance = app.DistanceSlider.Value;
            profile = profile / (distance^2);

            % Display the profile in UIAxes
            plot(app.UIAxes, profile);
            title(app.UIAxes, '1D Profile of 2D Phantom');
            xlabel(app.UIAxes, 'Position');
            ylabel(app.UIAxes, 'Intensity');
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: EnergyDropDown
        function EnergyDropDownValueChanged(app, event)
            % update mu values when dropfown selection changes
            updateMuValues(app);
        end

        % Value changed function: Generate2DImageButton
        function Generate2DImageButtonValueChanged(app, event)
            % generate the 2d profile when button is pressed
            generate2DProfile(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'X-Ray Simulation')
            xlabel(app.UIAxes, 'Position')
            ylabel(app.UIAxes, 'Intensity')
            zlabel(app.UIAxes, 'Z')
            app.EnergyDropDownLabel = uilabel(app.UIFigure);
            app.EnergyDropDownLabel.Text = 'Energy';
            app.EnergyDropDown = uidropdown(app.UIFigure);
            app.EnergyDropDown.Items = {'20 keV', '30 keV', '50 keV'};
            app.EnergyDropDown.ValueChangedFcn = createCallbackFcn(app, @EnergyDropDownValueChanged, true);
            app.EnergyDropDown.Value = '20 keV';
            app.Generate2DImageButton = uibutton(app.UIFigure, 'state');
            app.Generate2DImageButton.ValueChangedFcn = createCallbackFcn(app, @Generate2DImageButtonValueChanged, true);
            app.Generate2DImageButton.Text = 'Generate 2D Image';
            app.Generate3DImageButton = uibutton(app.UIFigure, 'state');
            app.Generate3DImageButton.Text = 'Generate 3D Image';
            app.AngleSliderLabel = uilabel(app.UIFigure);
            app.AngleSliderLabel.Text = 'Angle';
            app.AngleSlider = uislider(app.UIFigure);
            app.AngleSlider.Limits = [0 180];
            app.DistanceSliderLabel = uilabel(app.UIFigure);
            app.DistanceSliderLabel.Text = 'Distance';
            app.DistanceSlider = uislider(app.UIFigure);
            app.DistanceSlider.Limits = [50 200];
            app.DistanceSlider.Value = 50;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MammographySimulatorApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end